classdef miApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        UITable        matlab.ui.control.Table
        MostrarButton  matlab.ui.control.Button
        UIAxes         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MostrarButton
        function MostrarButtonPushed(app, event)
            table = readtable('C:\Users\Alejandro\Documents\PROTECO\MATLAB\proyecto\Admision.csv');
            app.UITable.Data = table;

            columna1 = table.Chance_of_Admit;
            columna2 = table.TOEFL_Score;

            scatter(app.UIAxes, columna1, columna2);

            title(app.UIAxes, 'Gráfico para saber la probabilidad de admision');
            xlabel(app.UIAxes, 'TOEFL_Score');
            ylabel(app.UIAxes, 'Chance_of_Admit');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.MostrarButton = uibutton(app.UIFigure, 'push');
            app.MostrarButton.ButtonPushedFcn = createCallbackFcn(app, @MostrarButtonPushed, true);
            app.MostrarButton.Text = 'Mostrar';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Serial_No'; 'GRE_Score'; 'TOEFL_Score'; 'University Rating'; 'SOP'; 'LOR'; 'CGPA'; 'Research'; 'Chance_of_Admit'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = miApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end